classdef TonicCurrent_Viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ToniccurrentanalysisLabel  matlab.ui.control.Label
        PassivepropertiesLabel     matlab.ui.control.Label
        HoldingcurrentLabel        matlab.ui.control.Label
        CheckBox_Ihold             matlab.ui.control.CheckBox
        CheckBox_passive           matlab.ui.control.CheckBox
        ExitButton                 matlab.ui.control.Button
        TraceSpinner               matlab.ui.control.Spinner
        TraceSpinnerLabel          matlab.ui.control.Label
        UIAxes_7                   matlab.ui.control.UIAxes
        UIAxes_6                   matlab.ui.control.UIAxes
        UIAxes_5                   matlab.ui.control.UIAxes
        UIAxes_4                   matlab.ui.control.UIAxes
        UIAxes_3                   matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % input from base workspace
        data
        data_Ihold
        Ntraces
        Median_Ihold_perSweep
        Mode_Ihold_perSweep
        Hist_Ihold_perSweep
        Hist_bincenters
        opt
        I_hold
        I_peak
        I_ss
        Ra
        Rm
        hold_range
        peak_range
        ss_range

        % in app
        ymin
        ymax
        time
        xmin
        xmax
        xmax2
        time_pulse
        xmin_pulse
        xmax_pulse
        ymin_pulse
        ymax_pulse
        ymin_Ra
        ymax_Ra
        ymin_Rm
        ymax_Rm
        selectedTraces_Ihold % output to base workspace
        selectedTraces_Rm    % output to base workspace
        previousROI
        currentROI
    end
    
    methods (Access = private)
        
        function plotdata(app)
            cla(app.UIAxes, 'reset')
            app.UIAxes.PositionConstraint = 'innerposition'; % it is critical that this comes first
            hold(app.UIAxes, "on")
            plot(app.UIAxes, app.time, app.data(:, app.TraceSpinner.Value*2), 'black')
            yline(app.UIAxes, app.Median_Ihold_perSweep(app.TraceSpinner.Value), 'Color', [0 1 1], 'LineStyle', '--', 'LineWidth', 2);
            yline(app.UIAxes, app.Mode_Ihold_perSweep(app.TraceSpinner.Value), 'Color', [1 0 0], 'LineStyle', ':', 'LineWidth', 2);
            ylim(app.UIAxes, [app.ymin, app.ymax])
            xlim(app.UIAxes, [app.xmin, app.xmax])
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, 'Im [pA]')
            legend(app.UIAxes, {'Im','Median Ihold','Modal Ihold'})
            hold(app.UIAxes, "off")
            %
            cla(app.UIAxes_2, 'reset')
            app.UIAxes_2.PositionConstraint = 'innerposition'; % it is critical that this comes first
            hold(app.UIAxes_2, "on")
            plot(app.UIAxes_2, app.Hist_Ihold_perSweep(:, app.TraceSpinner.Value), app.Hist_bincenters, 'black')
            yline(app.UIAxes_2, app.Median_Ihold_perSweep(app.TraceSpinner.Value), 'Color', [0 1 1], 'LineStyle', '--', 'LineWidth', 2);
            yline(app.UIAxes_2, app.Mode_Ihold_perSweep(app.TraceSpinner.Value), 'Color', [1 0 0], 'LineStyle', ':', 'LineWidth', 2);
            ylim(app.UIAxes_2, [app.ymin, app.ymax])
            xlim(app.UIAxes_2, [0, app.xmax2])
            xlabel(app.UIAxes_2, 'Relative frequency []')
            hold(app.UIAxes_2, "off")
            %
            cla(app.UIAxes_3, 'reset')
            app.UIAxes_3.PositionConstraint = 'innerposition'; % it is critical that this comes first
            hold(app.UIAxes_3, "on")
            area(app.UIAxes_3, app.time(app.peak_range), ones(1, numel(app.peak_range)).*app.ymax_pulse, app.ymin_pulse, 'FaceColor', [1 0.9 0.9], 'LineStyle', 'none');
            plot(app.UIAxes_3, app.time, app.data(:, app.TraceSpinner.Value*2), 'black')
            yline(app.UIAxes_3, app.I_hold(app.TraceSpinner.Value), 'Color', [0 1 0], 'LineStyle', ':', 'LineWidth', 2);
            yline(app.UIAxes_3, app.I_peak(app.TraceSpinner.Value), 'Color', [1 0 0], 'LineStyle', ':', 'LineWidth', 2);
            yline(app.UIAxes_3, app.I_ss(app.TraceSpinner.Value), 'Color', [1 0 1], 'LineStyle', ':', 'LineWidth', 2);
            ylim(app.UIAxes_3, [app.ymin_pulse, app.ymax_pulse])
            xlim(app.UIAxes_3, [app.xmin_pulse, app.xmax_pulse])
            xlabel(app.UIAxes_3, 'Time [s]')
            ylabel(app.UIAxes_3, 'Im [pA]')
            legend(app.UIAxes_3, {'Ipeak range', 'Im','Ihold','Ipeak', 'Iss'})
            hold(app.UIAxes_3, "off")
            %
            cla(app.UIAxes_4, 'reset')
            app.UIAxes_4.PositionConstraint = 'innerposition'; % it is critical that this comes first
            hold(app.UIAxes_4, "on")
            scatter(app.UIAxes_4, 1:app.Ntraces, app.Mode_Ihold_perSweep, 36, [0 0 0]);
            scatter(app.UIAxes_4, app.TraceSpinner.Value, app.Mode_Ihold_perSweep(app.TraceSpinner.Value), 36, [1 0 0], 'filled');
            ylim(app.UIAxes_4, [app.ymin, app.ymax])
            xlim(app.UIAxes_4, [0, app.Ntraces+1])
            ylabel(app.UIAxes_4, 'Modal Ihold [pA]')
            xlabel(app.UIAxes_4, 'Trace number []')
            hold(app.UIAxes_4, "off")
            %
            cla(app.UIAxes_5, 'reset')
            app.UIAxes_5.PositionConstraint = 'innerposition'; % it is critical that this comes first
            hold(app.UIAxes_5, "on")
            scatter(app.UIAxes_5, 1:app.Ntraces, app.Ra, 36, [0 0 0]);
            scatter(app.UIAxes_5, app.TraceSpinner.Value, app.Ra(app.TraceSpinner.Value), 36, [1 0 0], 'filled');
            ylim(app.UIAxes_5, [app.ymin_Ra, app.ymax_Ra])
            xlim(app.UIAxes_5, [0, app.Ntraces+1])
            ylabel(app.UIAxes_5, 'Ra [MOhm]')
            xlabel(app.UIAxes_5, 'Trace number []')
            hold(app.UIAxes_5, "off")
            %
            cla(app.UIAxes_6, 'reset')
            app.UIAxes_6.PositionConstraint = 'innerposition'; % it is critical that this comes first
            hold(app.UIAxes_6, "on")
            scatter(app.UIAxes_6, 1:app.Ntraces, app.Rm, 36, [0 0 0]);
            scatter(app.UIAxes_6, app.TraceSpinner.Value, app.Rm(app.TraceSpinner.Value), 36, [1 0 0], 'filled');
            ylim(app.UIAxes_6, [app.ymin_Rm, app.ymax_Rm])
            xlim(app.UIAxes_6, [0, app.Ntraces+1])
            ylabel(app.UIAxes_6, 'Rm [MOhm]')
            xlabel(app.UIAxes_6, 'Trace number []')
            hold(app.UIAxes_6, "off")
            %
            cla(app.UIAxes_7, 'reset')
            app.UIAxes_7.PositionConstraint = 'innerposition'; % it is critical that this comes first
            hold(app.UIAxes_7, "on")
            area(app.UIAxes_7, app.time(app.hold_range), ones(1, numel(app.hold_range)).*app.ymax, app.ymin, 'FaceColor', [0.9 1 0.9], 'LineStyle', 'none');
            area(app.UIAxes_7, app.time(app.ss_range), ones(1, numel(app.ss_range)).*app.ymax, app.ymin, 'FaceColor', [1 0.9 1], 'LineStyle', 'none');
            plot(app.UIAxes_7, app.time, app.data(:, app.TraceSpinner.Value*2), 'black')
            yline(app.UIAxes_7, app.I_hold(app.TraceSpinner.Value), 'Color', [0 1 0], 'LineStyle', ':', 'LineWidth', 2);
            yline(app.UIAxes_7, app.I_peak(app.TraceSpinner.Value), 'Color', [1 0 0], 'LineStyle', ':', 'LineWidth', 2);
            yline(app.UIAxes_7, app.I_ss(app.TraceSpinner.Value), 'Color', [1 0 1], 'LineStyle', ':', 'LineWidth', 2);
            ylim(app.UIAxes_7, [app.ymin, app.ymax])
            xlim(app.UIAxes_7, [app.xmin_pulse, app.xmax_pulse])
            xlabel(app.UIAxes_7, 'Time [s]')
            ylabel(app.UIAxes_7, 'Im [pA]')
            legend(app.UIAxes_7, {'Ihold range', 'Iss range', 'Im','Ihold','Ipeak', 'Iss'})
            hold(app.UIAxes_7, "off")
        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % get from base workspace
            app.data = evalin('base','data');
            app.data_Ihold = evalin('base', 'data_Ihold');
            app.Ntraces = evalin('base','Nsweeps');
            app.Median_Ihold_perSweep = evalin('base','Median_Ihold_perSweep');
            app.Mode_Ihold_perSweep = evalin('base','Mode_Ihold_perSweep');
            app.Hist_Ihold_perSweep = evalin('base', 'Hist_Ihold_perSweep');
            app.Hist_bincenters = evalin('base', 'Hist_bincenters');
            app.opt = evalin('base', 'opt');
            app.I_hold = evalin('base', 'I_hold');
            app.I_peak = evalin('base', 'I_peak');
            app.I_ss = evalin('base', 'I_ss');
            app.Ra = evalin('base', 'Ra');
            app.Rm = evalin('base', 'Rm');
            app.hold_range = evalin('base', 'hold_range');
            app.peak_range = evalin('base', 'peak_range');
            app.ss_range = evalin('base', 'ss_range');
            %
            % compute plotting range
            app.ymin = median(app.data_Ihold(:) - 10*iqr(app.data_Ihold(:)));
            app.ymax = median(app.data_Ihold(:) + 10*iqr(app.data_Ihold(:)));
            app.time = app.data(:, 1) ./ 1000; % [s]
            app.xmin = min(app.time); % x-axis min
            app.xmax = max(app.time); % x-axis max
            app.xmax2 = max(app.Hist_Ihold_perSweep(:)) * 1.2;
            app.xmin_pulse = app.opt.b - (app.opt.c - app.opt.b)/2;
            app.xmax_pulse = app.opt.c;
            app.ymin_pulse = min(min(app.data(:, 2:end)));
            app.ymax_pulse = max(max(app.data(:, 2:end)));
            app.ymin_Ra = 0;
            app.ymax_Ra = max(app.Ra) * 1.2;
            app.ymin_Rm = min(app.Rm) * 0.5;
            app.ymax_Rm = max(app.Rm) * 1.5;
            %
            % initialize
            app.selectedTraces_Ihold = ones(1, app.Ntraces);
            app.selectedTraces_Rm = ones(1, app.Ntraces);
            app.TraceSpinner.Value = 1;
            app.TraceSpinner.Limits = [1, app.Ntraces];
            app.previousROI = 1; % initialize
            app.currentROI = 1;
            plotdata(app)
            %
            app.CheckBox_Ihold.Value = 1;
            app.CheckBox_passive.Value = 1;
        end

        % Value changed function: TraceSpinner
        function TraceSpinnerValueChanged(app, event)
            app.currentROI = app.TraceSpinner.Value;
            % update selected candidate templates for the previous ROI
            app.selectedTraces_Ihold(app.previousROI) = app.CheckBox_Ihold.Value;
            app.selectedTraces_Rm(app.previousROI) = app.CheckBox_passive.Value;
            % set checkbox values
            app.CheckBox_Ihold.Value = app.selectedTraces_Ihold(app.currentROI);
            app.CheckBox_passive.Value = app.selectedTraces_Rm(app.currentROI);
            %
            app.previousROI = app.currentROI;
            %
            plotdata(app);
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            % update selected candidate templates for the current ROI
            app.currentROI = app.TraceSpinner.Value;
            app.selectedTraces_Ihold(1, app.currentROI) = app.CheckBox_Ihold.Value;
            app.selectedTraces_Rm(1, app.currentROI) = app.CheckBox_passive.Value;
            %
            assignin('base','selectedTraces_Ihold', app.selectedTraces_Ihold);
            assignin('base','selectedTraces_Rm', app.selectedTraces_Rm);
            assignin('base','appexit', 1);
            app.delete
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_7 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_7, 'Z')
            app.TraceSpinnerLabel = uilabel(app.UIFigure);
            app.TraceSpinnerLabel.Text = 'Trace';
            app.TraceSpinner = uispinner(app.UIFigure);
            app.TraceSpinner.ValueChangedFcn = createCallbackFcn(app, @TraceSpinnerValueChanged, true);
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.CheckBox_passive = uicheckbox(app.UIFigure);
            app.CheckBox_passive.Text = 'De-select to exclude';
            app.CheckBox_Ihold = uicheckbox(app.UIFigure);
            app.CheckBox_Ihold.Text = 'De-select to exclude';
            app.HoldingcurrentLabel = uilabel(app.UIFigure);
            app.HoldingcurrentLabel.Text = 'Holding current';
            app.PassivepropertiesLabel = uilabel(app.UIFigure);
            app.PassivepropertiesLabel.Text = 'Passive properties';
            app.ToniccurrentanalysisLabel = uilabel(app.UIFigure);
            app.ToniccurrentanalysisLabel.Text = 'Tonic current analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TonicCurrent_Viewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end